classdef MorphologiKelompokJ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PengolahanCitraDigitalCLabel  matlab.ui.control.Label
        KelompokJLabel                matlab.ui.control.Label
        IGustiNgurahBagusPicessaKresnaMandala2105551097Label  matlab.ui.control.Label
        MadeMarshallViraDeva2105551093Label  matlab.ui.control.Label
        MadeWahyuAdwityaPramana2105551092Label  matlab.ui.control.Label
        IKomangCleonKalea2105551090Label  matlab.ui.control.Label
        MorphologiCitraLabel          matlab.ui.control.Label
        RangeStrelEditField           matlab.ui.control.NumericEditField
        RangeStrelEditFieldLabel      matlab.ui.control.Label
        CitraHasilOperasiLabel        matlab.ui.control.Label
        CitraBinerLabel               matlab.ui.control.Label
        CitraGrayscaleLabel           matlab.ui.control.Label
        CitraAsliLabel                matlab.ui.control.Label
        OperasiButtonGroup            matlab.ui.container.ButtonGroup
        OpeningButton                 matlab.ui.control.RadioButton
        ThickeningButton              matlab.ui.control.RadioButton
        ClosingButton                 matlab.ui.control.RadioButton
        ErosiButton                   matlab.ui.control.RadioButton
        DilasiButton                  matlab.ui.control.RadioButton
        ThinningButton                matlab.ui.control.RadioButton
        ConvertBinerButton            matlab.ui.control.Button
        ProsesButton                  matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        StructureElementButtonGroup   matlab.ui.container.ButtonGroup
        DiskButton                    matlab.ui.control.RadioButton
        DiamondButton                 matlab.ui.control.RadioButton
        SquareButton                  matlab.ui.control.RadioButton
        LineButton                    matlab.ui.control.RadioButton
        MasukanCitraButton            matlab.ui.control.Button
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imgasli % Description
        imggray % Description
        imgbiner % Description
        imghasil % Description
        imgerosi % Description
        imgdilatasi % Description
        imgthinning % Description
        imgclosing % Description
        imgopening % Description
        imgthickening % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MasukanCitraButton
        function MasukGambar(app, event)
            [file, path] = uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.imgasli = imread(fullfile(path,file));
            imshow(app.imgasli,'parent',app.UIAxes);

            app.imggray = rgb2gray(app.imgasli);
            imshow(app.imggray,'parent',app.UIAxes_2);
        end

        % Button pushed function: ProsesButton
        function ProsesGambar(app, event)
            nilairangestrel=app.RangeStrelEditField.Value;
            
            if app.ErosiButton.Value==1
                if app.DiskButton.Value==1
                    se = strel("disk",nilairangestrel);
                elseif app.DiamondButton.Value==1
                    se = strel("diamond",nilairangestrel);
                elseif app.SquareButton.Value==1
                    se = strel("square",nilairangestrel);
                elseif app.LineButton.Value==1
                    se = strel("line",nilairangestrel,180);
                end

                app.imgerosi = imerode(app.imgbiner,se);
                imshow(app.imgerosi,'parent',app.UIAxes_4);

            elseif app.DilasiButton.Value==1
                if app.DiskButton.Value==1
                    se = strel("disk",nilairangestrel);
                elseif app.DiamondButton.Value==1
                    se = strel("diamond",nilairangestrel);
                elseif app.SquareButton.Value==1
                    se = strel("square",nilairangestrel);
                elseif app.LineButton.Value==1
                    se = strel("line",nilairangestrel,90);
                end

                app.imgdilatasi = imdilate(app.imgbiner,se);
                imshow(app.imgdilatasi,'parent',app.UIAxes_4);

            elseif app.OpeningButton.Value==1
                if app.DiskButton.Value==1
                    se = strel("disk",nilairangestrel);
                elseif app.DiamondButton.Value==1
                    se = strel("diamond",nilairangestrel);
                elseif app.SquareButton.Value==1
                    se = strel("square",nilairangestrel);
                elseif app.LineButton.Value==1
                    se = strel("line",nilairangestrel,90);
                end

                app.imgopening = imdilate(imerode(app.imgbiner,se),se);
                imshow(app.imgopening,'parent',app.UIAxes_4);
                
            elseif app.ClosingButton.Value==1
                if app.DiskButton.Value==1
                    se = strel("disk",nilairangestrel);
                elseif app.DiamondButton.Value==1
                    se = strel("diamond",nilairangestrel);
                elseif app.SquareButton.Value==1
                    se = strel("square",nilairangestrel);
                elseif app.LineButton.Value==1
                    se = strel("line",nilairangestrel,90);
                end

                app.imgclosing = imerode(imdilate(app.imgbiner,se),se);
                imshow(app.imgclosing,'parent',app.UIAxes_4);

            elseif app.ThickeningButton.Value==1
                app.imgthickening = bwmorph(app.imgbiner,'thicken',nilairangestrel);

                imshow(app.imgthickening,'parent',app.UIAxes_4);

            elseif app.ThinningButton.Value==1
                app.imgthinning = bwmorph(app.imgbiner,'thin',nilairangestrel);

                imshow(app.imgthinning,'parent',app.UIAxes_4);
            end
        end

        % Button pushed function: ResetButton
        function ResetGambar(app, event)
            cla(app.UIAxes,'reset');
            
            cla(app.UIAxes_2,'reset')

            cla(app.UIAxes_3,'reset')

            cla(app.UIAxes_4,'reset')
        end

        % Button pushed function: ExitButton
        function Keluar(app, event)
            closereq()
        end

        % Button pushed function: ConvertBinerButton
        function Biner(app, event)
            app.imgbiner = not(imbinarize(app.imggray));
            imshow(app.imgbiner,'parent',app.UIAxes_3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.MasukanCitraButton = uibutton(app.UIFigure, 'push');
            app.MasukanCitraButton.ButtonPushedFcn = createCallbackFcn(app, @MasukGambar, true);
            app.MasukanCitraButton.Text = 'Masukan Citra';
            app.StructureElementButtonGroup = uibuttongroup(app.UIFigure);
            app.StructureElementButtonGroup.Title = 'Structure Element';
            app.LineButton = uiradiobutton(app.StructureElementButtonGroup);
            app.LineButton.Text = 'Line';
            app.SquareButton = uiradiobutton(app.StructureElementButtonGroup);
            app.SquareButton.Text = 'Square';
            app.DiamondButton = uiradiobutton(app.StructureElementButtonGroup);
            app.DiamondButton.Text = 'Diamond';
            app.DiskButton = uiradiobutton(app.StructureElementButtonGroup);
            app.DiskButton.Text = 'Disk';
            app.DiskButton.Value = true;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGambar, true);
            app.ResetButton.Text = 'Reset';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @Keluar, true);
            app.ExitButton.Text = 'Exit';
            app.ProsesButton = uibutton(app.UIFigure, 'push');
            app.ProsesButton.ButtonPushedFcn = createCallbackFcn(app, @ProsesGambar, true);
            app.ProsesButton.Text = 'Proses';
            app.ConvertBinerButton = uibutton(app.UIFigure, 'push');
            app.ConvertBinerButton.ButtonPushedFcn = createCallbackFcn(app, @Biner, true);
            app.ConvertBinerButton.Text = 'Convert Biner';
            app.OperasiButtonGroup = uibuttongroup(app.UIFigure);
            app.OperasiButtonGroup.Title = 'Operasi';
            app.ThinningButton = uiradiobutton(app.OperasiButtonGroup);
            app.ThinningButton.Text = 'Thinning';
            app.DilasiButton = uiradiobutton(app.OperasiButtonGroup);
            app.DilasiButton.Text = 'Dilasi';
            app.ErosiButton = uiradiobutton(app.OperasiButtonGroup);
            app.ErosiButton.Text = 'Erosi';
            app.ErosiButton.Value = true;
            app.ClosingButton = uiradiobutton(app.OperasiButtonGroup);
            app.ClosingButton.Text = 'Closing';
            app.ThickeningButton = uiradiobutton(app.OperasiButtonGroup);
            app.ThickeningButton.Text = 'Thickening';
            app.OpeningButton = uiradiobutton(app.OperasiButtonGroup);
            app.OpeningButton.Text = 'Opening';
            app.CitraAsliLabel = uilabel(app.UIFigure);
            app.CitraAsliLabel.Text = 'Citra Asli';
            app.CitraGrayscaleLabel = uilabel(app.UIFigure);
            app.CitraGrayscaleLabel.Text = 'Citra Grayscale';
            app.CitraBinerLabel = uilabel(app.UIFigure);
            app.CitraBinerLabel.Text = 'Citra Biner';
            app.CitraHasilOperasiLabel = uilabel(app.UIFigure);
            app.CitraHasilOperasiLabel.Text = 'Citra Hasil Operasi';
            app.RangeStrelEditFieldLabel = uilabel(app.UIFigure);
            app.RangeStrelEditFieldLabel.Text = 'Range Strel';
            app.RangeStrelEditField = uieditfield(app.UIFigure, 'numeric');
            app.RangeStrelEditField.Limits = [1 Inf];
            app.RangeStrelEditField.ValueDisplayFormat = '%.0f';
            app.RangeStrelEditField.Value = 1;
            app.MorphologiCitraLabel = uilabel(app.UIFigure);
            app.MorphologiCitraLabel.Text = 'Morphologi Citra';
            app.IKomangCleonKalea2105551090Label = uilabel(app.UIFigure);
            app.IKomangCleonKalea2105551090Label.Text = {'I Komang Cleon Kalea'; '(2105551090)'};
            app.MadeWahyuAdwityaPramana2105551092Label = uilabel(app.UIFigure);
            app.MadeWahyuAdwityaPramana2105551092Label.Text = {'Made Wahyu Adwitya Pramana'; '(2105551092)'};
            app.MadeMarshallViraDeva2105551093Label = uilabel(app.UIFigure);
            app.MadeMarshallViraDeva2105551093Label.Text = {'Made Marshall Vira Deva'; '(2105551093)'};
            app.IGustiNgurahBagusPicessaKresnaMandala2105551097Label = uilabel(app.UIFigure);
            app.IGustiNgurahBagusPicessaKresnaMandala2105551097Label.Text = {'I Gusti Ngurah Bagus Picessa Kresna Mandala '; '(2105551097)'};
            app.KelompokJLabel = uilabel(app.UIFigure);
            app.KelompokJLabel.Text = 'Kelompok J';
            app.PengolahanCitraDigitalCLabel = uilabel(app.UIFigure);
            app.PengolahanCitraDigitalCLabel.Text = 'Pengolahan Citra Digital - C';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MorphologiKelompokJ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end